classdef new_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        StatusTextArea              matlab.ui.control.TextArea
        TextAreaLabel               matlab.ui.control.Label
        GPUCheckBox                 matlab.ui.control.CheckBox
        rangeEditField              matlab.ui.control.EditField
        rangeEditFieldLabel         matlab.ui.control.Label
        generatespatialbaseButton   matlab.ui.control.Button
        ViewcontinuetimebaseButton  matlab.ui.control.Button
        ViewsingletimebaseButton    matlab.ui.control.Button
        sensitivityDropDown         matlab.ui.control.DropDown
        sensitivityDropDownLabel    matlab.ui.control.Label
        LoadSDButton                matlab.ui.control.Button
        ConditonDropDown            matlab.ui.control.DropDown
        ConditonDropDownLabel       matlab.ui.control.Label
        HRFCheckBox                 matlab.ui.control.CheckBox
        DataTree                    matlab.ui.container.Tree
        ProcessinglevelButtonGroup  matlab.ui.container.ButtonGroup
        SessButton                  matlab.ui.control.RadioButton
        RunButton                   matlab.ui.control.RadioButton
        SubjButton                  matlab.ui.control.RadioButton
        GroupButton                 matlab.ui.control.RadioButton
        SignalListBox               matlab.ui.control.ListBox
        PlotButton                  matlab.ui.control.Button
        ChannelDropDown             matlab.ui.control.DropDown
        ChannelDropDownLabel        matlab.ui.control.Label
        PlotTypeButtonGroup         matlab.ui.container.ButtonGroup
        ConcButton                  matlab.ui.control.RadioButton
        ODButton                    matlab.ui.control.RadioButton
        RawdataButton               matlab.ui.control.RadioButton
        EditOptionButton            matlab.ui.control.Button
        RunImageReconExp            matlab.ui.control.Button
        BrowseData                  matlab.ui.control.Button
        UIAxes                      matlab.ui.control.UIAxes
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    
    properties (Access = private)
        Property % Description
        AdvApp
        ds % data source
        cache % current process
        sel % user selection
        cfg
    end
    
    methods (Access = private)


        %% This function update the "Signal List" box on the UI
        % It check which plot type the user selected Raw/OD/Conc.
        % e.g: user select Conc -> it show (like Hbo/HbR/HbT)
        % After creating this list, the function updates the SignalListBox on the UI 
        function updateSignalList(app)
            plotType = app.PlotTypeButtonGroup.SelectedObject.Text;
            app.ds.waveLength = app.ds.firstRun.GetWls();

            items = {};
            data  = [];   % numeric indicecs

            % user select Raw/OD -> it will show (like 670nm, 850nm)
            if strcmp(plotType, 'Raw data') || strcmp(plotType, 'OD')
                for i = 1:numel(app.ds.waveLength)
                    items{i} = sprintf('%dnm', round(app.ds.waveLength(i)));
                    data(i) = i;
                end
                
            % user select Conc -> it show (like Hbo/HbR/HbT)
            elseif strcmp(plotType, 'Conc')
                items{1} = 'HbO';
                items{2} = 'HbR';
                items{3} = 'HbT';
                data = [0 1 2];
            end

            % update Signal List Box
            app.SignalListBox.Items = items;
            app.SignalListBox.ItemsData = data;
            app.SignalListBox.Value = data(1);
            drawnow;
        end
        

        %%  Parse channel label text "TxX–RxY" into numeric ID
        function [s,d] = parseChannelLabel(~,label)
            % extract 2 numbers: after 'Tx' and after 'Rx', with any non-digit separator between
            tok = regexp(label,'Tx(\d+)[^\d]+Rx(\d+)','tokens','once');
            s = str2double(tok{1});
            d = str2double(tok{2});
        end


        %% Parse the integer number before 'nm'
        function waveLengthIdx = nmLabelToIndex(app, nmLabel)
            waveLengthIdx = [];
            nm = sscanf(nmLabel, '%dnm');
            if isempty(nm) || isempty(app.ds.wls)
                waveLengthIdx = [];
                return;
            end
            % Find the first index where wavelength equals that number
            waveLengthIdx = find(app.ds.wls == nm, 1);
        end
   

        %% Get channel pair list Tx#-Rx#
        function getChannelList(app)
            % I pick first run to get channel list
            acq = app.ds.firstRun.acquired;
            acq.Load()
            assignin('base', 'acq', acq);
            ml = app.ds.firstRun.acquired.data.measurementList;

            if isempty(ml)
                uialert(app.UIFigure, 'measurementList is empty.', 'Error');
                return;
            end

            sourceArray = zeros(numel(ml), 1);
            detectorArray = zeros(numel(ml), 1);

            %fill the arrays
            for i = 1:(numel(ml))
                sourceArray(i) = ml(i).sourceIndex;
                detectorArray(i) = ml(i).detectorIndex;
            end

            % Combine Tx and Rx into one nx2 array
            % make a list of UNIQUE [Tx,Rx] pairs
            % keep only the first occurrence of each pair
            pairsRaw = [sourceArray detectorArray];
            pairs = []; % start with empty list

            for i = 1:size(pairsRaw, 1)
                isExist = false;
                row = pairsRaw(i,:);

                % check if this pair is already in 'pairs'
                for j = 1:size(pairs, 1)
                    if all(row == pairs(j,:))
                        isExist = true;
                        break;
                    end 
                end

                % If the pair is new, add it toe the list
                if isExist == false
                    pairs = [pairs; row];
                end
            end


            % create label and map, update channel to ListBox of channel
            rowSize = size(pairs, 1);
            channelMap(rowSize, 1) = struct('label', "", 'source', 0, 'detector', 0);

            for i = 1:rowSize
                source = pairs(i, 1);
                detector = pairs(i, 2);

                label = sprintf('Tx%d-Rx%d', source, detector);

                channelMap(i).label = label;
                channelMap(i).source = source;
                channelMap(i).detector = detector;

                %fprintf('Channel %d: s=%d, d=%d, label=%s\n', i, source, detector, label);
            end


            % update channelDropDown on GUI
            items = {channelMap.label}.';
            app.ChannelDropDown.Items = items;
            app.ChannelDropDown.Value = app.ChannelDropDown.Items{1};
            drawnow;

        end


        %%
        % Build the tree view like:
        % Group 
        %      Subject
        %             Session 
        %                    Run
        % Each node get a label (the output filename) and a NodeData struct that stores its Index 
        % [groupIdx subjIdx sessIdx runIdx]. later on figure out exactly which file the user clicked in the tree.
        function buildTreeFromGroup(app, group)
            ig = max(1, group.iGroup);
        
            groupNode = uitreenode(app.DataTree, ...
                "Text", char(group.GetOutputFilename()), ...
                "NodeData", struct('type','group','idx',[ig 0 0 0]));
        
            for isubj = 1:numel(group.subjs)
                subj = group.subjs(isubj);
                subjNode = uitreenode(groupNode, ...
                    "Text", char(subj.GetOutputFilename()), ...
                    "NodeData", struct('type','subj','idx',[ig isubj 0 0]));
        
                for isess = 1:numel(subj.sess)
                    sess = subj.sess(isess);
                    sessNode = uitreenode(subjNode, ...
                        "Text", char(sess.GetOutputFilename()), ...
                        "NodeData", struct('type','sess','idx',[ig isubj isess 0]));
        
                    for irun = 1:numel(sess.runs)
                        run = sess.runs(irun);
                        uitreenode(sessNode, ...
                            "Text", char(run.GetOutputFilename()), ...
                            "NodeData", struct('type','run','idx',[ig isubj isess irun]));
                    end
                    expand(sessNode);
                end
                expand(subjNode);
            end
            expand(groupNode);
        end


        function updateConditionList(app)
            condNames = app.ds.firstRun.CondNames;
            items = condNames;

            app.ConditonDropDown.Items = items;
            app.ConditonDropDown.Value = items{1};
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            % DEFAULT DEFAULT
            appDir  = fileparts(mfilename('fullpath')); % .../SSDOT-main/GUI
            rootDir = fileparts(appDir); % .../SSDOT-main
        

            addpath(genpath(fullfile(rootDir,'code')));
            addpath(genpath(fullfile(rootDir,'homer_function')));
            addpath(genpath(fullfile('FunctionForGUI')));
            addpath(genpath(fullfile(fileparts(mfilename('fullpath')), 'FunctionForGUI')));

            % addpath(genpath(fullfile(rootDir,'homer_class')));
            

            % DEFAULT VAR, user can change these parameter on GUI

            % SYNC UI

            % Other or Debug
            
    
        end

        % Button pushed function: BrowseData
        function BrowseDataButtonPushed(app, event)
            %% Open a folder selection to allow user to choose(browse) the data folder
            %% Then after select will immediately load GroupResults.m data and show the subjects/group/run list
            
            % let user pick Homer output folder eg: ST001 folder
            folderPath = uigetdir(pwd, 'Select the output homer folder');

            if isequal(folderPath,0)
                return; % user hit cancel
            end

            % ST001/derivatives/homer/groupResults.mat
            groupPath = fullfile(folderPath, 'derivatives', 'homer', 'groupResults.mat');
            S = load(groupPath, 'group');
            app.ds.groupResult = S.group;
            assignin('base','groupResult', app.ds.groupResult);

            
            % After load groupResults.mat, reset all run/session paths to your current folder before calling
            loadAllFile(app, folderPath, app.ds.groupResult);
            app.ds.firstRun = app.ds.groupResult(1).subjs(1).sess(1).runs(1);
            assignin('base','firstRun',app.ds.firstRun); % load into workspace for testing/debugging
            assignin('base', 'group', app.ds.groupResult);


            % Build the DataTree UI to display hierarchy  
            % (Group → Subject → Session → Run) in the app’s tree panel 
            buildTreeFromGroup(app, app.ds.groupResult);


            getChannelList(app);
            updateSignalList(app);
            updateConditionList(app);
        end

        % Selection changed function: PlotTypeButtonGroup
        function PlotTypeButtonGroupSelectionChanged(app, event)
            selectedText = app.PlotTypeButtonGroup.SelectedObject.Text;
            if strcmp(selectedText, "Raw data")
                app.HRFCheckBox.Enable = 'off';
            else
                app.HRFCheckBox.Enable = 'on';
            end
            updateSignalList(app);
            
        end

        % Selection changed function: ProcessinglevelButtonGroup
        function ProcessinglevelButtonGroupSelectionChanged(app, event)
            app.sel.processingLevel = app.ProcessinglevelButtonGroup.SelectedObject.Text;
            app.DataTree.SelectedNodes =[];
            
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            %% Wrap everything to check before hit plot button
            %% Basic guards
            if ~isfield(app.ds,'groupResult') || isempty(app.ds.groupResult)
                uialert(app.UIFigure,'Load a dataset first','No Data');
                return;
            end
            if ~isfield(app.sel,'file')
                uialert(app.UIFigure,'Select a RUN in the tree.','Info');
                return;
            end
            if isempty(app.ChannelDropDown.Value)
                uialert(app.UIFigure,'Pick a channel (Tx–Rx).','Info'); 
                return;
            end
            if isempty(app.SignalListBox.Value)
                uialert(app.UIFigure,'Pick at least one signal to plot.','Info'); 
                return;
            end

            cla(app.UIAxes, 'reset');


            % parse dropdown text "Tx#-Rx#" into NUMERIC IDs
            [app.sel.source, app.sel.detector] = app.parseChannelLabel(app.ChannelDropDown.Value);
            app.sel.waveLengthLabel = app.SignalListBox.Items;
            app.sel.selectSignal = app.SignalListBox.Value;
            app.sel.condName = app.ConditonDropDown.Value;

            % user slect processing level type and plot type
            ProcessLevelType = app.ProcessinglevelButtonGroup.SelectedObject.Text;
            PlotType = app.PlotTypeButtonGroup.SelectedObject.Text;

            cla(app.UIAxes);
            hold(app.UIAxes, 'on');
            app.cache.legendLabels = {};


            % Decide what to plot based on "Process Level" (Run, Session, Subject, Group) 
            % and "Plot Type" (OD or Conc).  
            % 
            %  If HRF checkbox is checked => plot averaged signal (dodAvg or dcAvg).  
            %  If HRF checkbox is not checked => plot continuous signal or every run 
            %  Run level = plot just the selected run
            %  Session level = plot all runs in that session (loop through runs) 
            %  Subject level = loop through all sessions + runs for one subject
            %  Group level = loop through all subjects, sessions, and runs 

            switch ProcessLevelType
                %% RUN LEVEL
                case 'Run'
                    % get the actual run object based on selection in tree
                    run = app.ds.groupResult.subjs(app.sel.file.subjIdx).sess(app.sel.file.sessIdx).runs(app.sel.file.runIdx);
                    switch PlotType
                        case 'Raw data'
                            plotRaw(app.UIAxes, run, app.sel);
                            xlabel(app.UIAxes,'Time (s)');
                            ylabel(app.UIAxes,'Raw');
                            title(app.UIAxes, sprintf('Raw — Tx%d–Rx%d', app.sel.source, app.sel.detector));
                        case 'OD'
                            % If HRF checkbox is ON => plot averaged OD
                            % If OFF => plot continuous OD signal
                            if (app.HRFCheckBox.Value == true)
                                plotODAvg(app.UIAxes, run, app.sel);
                            else
                                plotOD(app.UIAxes, run, app.sel);
                            end
                            xlabel(app.UIAxes,'Time (s)');
                            ylabel(app.UIAxes,'Intensity (a.u.)');
                            title(app.UIAxes, sprintf('Optical Density — Tx%d–Rx%d', app.sel.source, app.sel.detector));

                        case 'Conc'
                            % Same logic but for concentrations (HbO/HbR/HbT)
                            if (app.HRFCheckBox.Value == true)
                                plotConcAvg(app.UIAxes, run, app.sel);
                            else
                                plotConc(app.UIAxes, run, app.sel);
                            end
                            xlabel(app.UIAxes,'Time (s)');
                            ylabel(app.UIAxes, sprintf('(\\M = mol/L'));
                            title(app.UIAxes, sprintf('Conccentration — Tx%d–Rx%d', app.sel.source, app.sel.detector));
                    end


                %% Session level
                case 'Sess'
                    sess = app.ds.groupResult.subjs(app.sel.file.subjIdx).sess(app.sel.file.sessIdx);
                    switch PlotType
                        case 'Raw data'
                            for i = 1:numel(sess.runs)
                                plotRaw(app.UIAxes, sess.runs(i), app.sel);
                            end
                            xlabel(app.UIAxes,'Time (s)');
                            ylabel(app.UIAxes,'Raw');
                            title(app.UIAxes, sprintf('Raw — Tx%d–Rx%d', app.sel.source, app.sel.detector));
                        case 'OD'
                            if (app.HRFCheckBox.Value == true)
                                plotODAvg(app.UIAxes, sess, app.sel);
                            else
                                for i = 1:numel(sess.runs)
                                    plotOD(app.UIAxes, sess.runs(i), app.sel);
                                end
                            end
                            xlabel(app.UIAxes,'Time (s)');
                            ylabel(app.UIAxes,'Intensity (a.u.)');
                            title(app.UIAxes, sprintf('Optical Density — Tx%d–Rx%d', app.sel.source, app.sel.detector));

                        case 'Conc'
                            if (app.HRFCheckBox.Value == true)
                                plotConcAvg(app.UIAxes, sess, app.sel);
                            else %HRF = 0 uncheck
                                for i = 1:numel(sess.runs)
                                    plotConc(app.UIAxes, sess.runs(i), app.sel);
                                end
                            end
                            xlabel(app.UIAxes,'Time (s)');
                            ylabel(app.UIAxes, sprintf('(\\M = mol/L'));
                            title(app.UIAxes, sprintf('Conc — Tx%d–Rx%d', app.sel.source, app.sel.detector));
                    end
                   

                %% Subject level
                case 'Subj'
                    subjs = app.ds.groupResult.subjs(app.sel.file.subjIdx);
                    switch PlotType
                        case 'Raw data'
                            for i  = 1:numel(subjs.sess)
                                for j = 1:numel(subjs.sess(i).runs)
                                    plotRaw(app.UIAxes, subjs.sess(i).runs(j), app.sel);
                                end
                            end
                            xlabel(app.UIAxes,'Time (s)');
                            ylabel(app.UIAxes,'Raw');
                            title(app.UIAxes, sprintf('Raw — Tx%d–Rx%d', app.sel.source, app.sel.detector));
                            
                        case 'OD'
                            % HRF ON = averaged dodAvg
                            % HRF OFF = plot every run in that subj
                            if (app.HRFCheckBox.Value == true)
                                plotODAvg(app.UIAxes, subjs, app.sel);
                            else % HRF = OFF
                                for i = 1:numel(subjs.sess)
                                    for j = 1:numel(subjs.sess(i).runs)
                                        plotOD(app.UIAxes, subjs.sess(i).runs(j), app.sel);
                                    end
                                end
                            end
                            xlabel(app.UIAxes,'Time (s)');
                            ylabel(app.UIAxes,'Intensity (a.u.)');
                            title(app.UIAxes, sprintf('Optical Density — Tx%d–Rx%d', app.sel.source, app.sel.detector));
                            
                        case 'Conc'
                            if (app.HRFCheckBox.Value == true)
                                plotConcAvg(app.UIAxes, subjs, app.sel);
                            else
                                for i = 1:numel(subjs.sess)
                                    for j =1:numel(subjs.sess(i).runs)
                                        plotConc(app.UIAxes, subjs.sess(i).runs(j), app.sel)
                                    end
                                end
                            end
                            xlabel(app.UIAxes,'Time (s)');
                            ylabel(app.UIAxes, sprintf('(\\M = mol/L'));
                            title(app.UIAxes, sprintf('Conc — Tx%d–Rx%d', app.sel.source, app.sel.detector));
                    end
                    

                %% GROUP LEVEL
                case 'Group'
                    group = app.ds.groupResult;
                    switch PlotType
                        case 'Raw data'
                            for i = 1:numel(group.subjs)
                                for j = 1:numel(group.subjs(i).sess)
                                    for k = 1:numel(group.subjs(i).sess(j).runs)
                                        plotRaw(app.UIAxes, group.subjs(i).sess(j).runs(k), app.sel);
                                    end
                                end
                            end
                            xlabel(app.UIAxes,'Time (s)');
                            ylabel(app.UIAxes,'Raw');
                            title(app.UIAxes, sprintf('Raw — Tx%d–Rx%d', app.sel.source, app.sel.detector));
                        case 'OD'
                            if (app.HRFCheckBox.Value == true)
                                plotODAvg(app.UIAxes, group, app.sel);
                            else
                                for i = 1:numel(group.subjs)
                                    for j = 1:numel(group.subjs(i).sess)
                                        for k = 1:numel(group.subjs(i).sess(j).runs)
                                            plotOD(app.UIAxes, group.subjs(i).sess(j).runs(i), app.sel);
                                        end
                                    end
                                end
                            end
                                            
                            xlabel(app.UIAxes,'Time (s)');
                            ylabel(app.UIAxes,'Intensity (a.u.)');
                            title(app.UIAxes, sprintf('Optical Density — Tx%d–Rx%d', app.sel.source, app.sel.detector));
                            
                        case 'Conc'
                            if (app.HRFCheckBox.Value == true)
                                plotConcAvg(app.UIAxes, group, app.sel);
                            else
                                for i = 1:numel(group.subjs)
                                    for j = 1:numel(group.subjs(i).sess)
                                        for k = 1:numel(group.subjs(i).sess(j).runs)
                                            plotConc(app.UIAxes, group.subjs(i).sess(j).runs(i), app.sel);
                                        end
                                    end
                                end
                            end
                            xlabel(app.UIAxes,'Time (s)');
                            ylabel(app.UIAxes, sprintf('(\\M = mol/L'));
                            title(app.UIAxes, sprintf('Conc — Tx%d–Rx%d', app.sel.source, app.sel.detector));
                    end
            end
            legend(app.UIAxes,'show','Location','northeast');
            app.UIAxes.XTickMode = 'auto';
            app.UIAxes.YTickMode = 'auto';
            grid(app.UIAxes,'on');
            hold(app.UIAxes,'off');
            
        end

        % Selection changed function: DataTree
        function DataTreeSelectionChanged(app, event)
            % Handle selection in the DataTree (group/subj/sess/run)
            % Get whatever node the user just clicked on Data tree list
            selectedNodes = event.SelectedNodes;
            nodeData = selectedNodes.NodeData;
            disp(selectedNodes);
            disp(nodeData);

            % Figure out what level the user *wants* to work at (from processing level buttons)
            requiredLevel = lower(strtrim(app.ProcessinglevelButtonGroup.SelectedObject.Text));

            % If they clicked the wrong kind of node (ex: processing level = run but clicked subj folder on data list)
            if ~strcmp(nodeData.type, requiredLevel)
                app.DataTree.SelectedNodes = [];
                uialert(app.UIFigure, "Please select a " + requiredLevel + " file.", "Wrong level");
                return;
            end

            % Save the selected node info into app.sel.file for later use 
            % nodeData.idx = [groupIndex subjIndex sessIndex runIndex]
            app.sel.file = struct();
            app.sel.file.groupIdx = nodeData.idx(1);
            app.sel.file.subjIdx = nodeData.idx(2);
            app.sel.file.sessIdx = nodeData.idx(3);
            app.sel.file.runIdx = nodeData.idx(4);

        end

        % Button pushed function: EditOptionButton
        function EditOptionButtonPushed(app, event)
            % Open the advanced settings window
            % allow user change some parameters
            % Disable the button while the dialog is open
            app.EditOptionButton.Enable = "off";
        
            % Open Advanced settings dialog
            app.AdvApp = AdvancedSetting(app.cfg, @app.onAdvancedApply, @app.onAdvancedClose);
            app.AdvApp.UIFigure.WindowStyle = 'modal';
        
            % Ensure the button is re-enabled if user closes the window via [X]
            %app.AdvApp.UIFigure.CloseRequestFcn = @(src,evt) app.onAdvClosed();

        end

        % Button pushed function: RunImageReconExp
        function RunImageReconExpButtonPushed(app, event)
            %% nothing...
            
        end

        % Value changed function: HRFCheckBox
        function HRFCheckBoxValueChanged(app, event)
            if app.HRFCheckBox.Value
                app.ConditonDropDown.Enable = 'on';
            else
                app.ConditonDropDown.Enable = 'off';
            end
        end

        % Clicked callback: SignalListBox
        function SignalListBoxClicked(app, event)
            
        end

        % Button pushed function: LoadSDButton
        function LoadSDButtonPushed(app, event)
            
            % load atlasviewer state file
            [file, path] = uigetfile;
            if isequal(file,0)
                disp('User canceled file selection');
                return;
            end

            AtlasState = load(fullfile(path,file));
            
            % we need to save this AtlasState for global use; Ask Vu to
            % also save data somewhere; we need OD data for each channel;
            % we also need time basis
            hFig =  app.UIFigure;
            setappdata(hFig, 'AtlasState', AtlasState);
            
            disp('AtlasState loaded and stored in GUI.');

            % --- Create a new figure as a child of the app ---
            fig = figure('Name', 'Atlas Viewer', ...
                         'NumberTitle', 'off', ...
                         'Color', [1 1 1], ...
                         'Visible', 'on', ...
                         'Units', 'normalized', ...
                         'Position', [0.2 0.2 0.5 0.5], ...
                         'HandleVisibility', 'callback', ...
                         'CloseRequestFcn', @(src,evt) delete(src));
            % So if app closes, this figure also closes
            addlistener(app, 'ObjectBeingDestroyed', @(~,~) delete(fig));

            % --- First axes (e.g., left plot)
            ax1 = axes('Parent', fig, 'Position', [0.1 0.1 0.35 0.8]); 
            title(ax1, 'Left Hemisphere');
            hold(ax1, 'on');
            
            % --- Second axes (e.g., right plot)
            ax2 = axes('Parent', fig, 'Position', [0.55 0.1 0.35 0.8]);
            title(ax2, 'Right Hemisphere');
            hold(ax2, 'on');

            ax = axes('Parent', fig);
            hold(ax, 'on');
            axis(ax, 'equal');
            axis(ax, 'off');
            % create a function to plot head surface
            plot_head_surface(ax1,ax2, AtlasState)
            % create a function to plot SD on top of head surface
            % plot_SD(app.AtlasView, AtlasState) 

      %       h = trisurf(faces, brain_vertices(:,axes_order(1)), brain_vertices(:,axes_order(2)), brain_vertices(:,axes_order(3)), ...
      % int_at_pos,'facecolor','interp','edgealpha',0, 'visible','on'); 
      %       plot(app.AtlasView, x, y);
        end

        % Callback function
        function LoadSensPushed(app, event)
            % load pial sensitivity file
            [file, path] = uigetfile;
            AtlasState = load(fullfile(path,file));
            % create a function to plot pial surface
            plot_head_surface(app.AtlasView, AtlasState)
            % create a function to plot sensitivity on top of head surface
            plot_SD(app.AtlasView, AtlasState)
        end

        % Value changed function: sensitivityDropDown
        function SensVis(app, event)
            value = app.sensitivityDropDown.Value;
            % load pial sensitivity file
            [file, path] = uigetfile;
            Sensitivity = load(fullfile(path,file));
            if value == 'Head'
                % create a function to plot head surface and sensitivity
                plot_Sensitivity_Head(app.AtlasView, AtlasState,Sensitivity)
            elseif value == 'Brain'
                % create a function to plot head surface and sensitivity
                plot_Sensitivity_Pial(app.AtlasView, AtlasState,Sensitivity)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'Time')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.BrowseData = uibutton(app.UIFigure, 'push');
            app.BrowseData.ButtonPushedFcn = createCallbackFcn(app, @BrowseDataButtonPushed, true);
            app.BrowseData.Text = 'Browse';
            app.RunImageReconExp = uibutton(app.UIFigure, 'push');
            app.RunImageReconExp.ButtonPushedFcn = createCallbackFcn(app, @RunImageReconExpButtonPushed, true);
            app.RunImageReconExp.Text = 'Run reconstruct';
            app.EditOptionButton = uibutton(app.UIFigure, 'push');
            app.EditOptionButton.ButtonPushedFcn = createCallbackFcn(app, @EditOptionButtonPushed, true);
            app.EditOptionButton.Text = 'Edit Option';
            app.PlotTypeButtonGroup = uibuttongroup(app.UIFigure);
            app.PlotTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PlotTypeButtonGroupSelectionChanged, true);
            app.PlotTypeButtonGroup.Title = 'Plot Type';
            app.RawdataButton = uiradiobutton(app.PlotTypeButtonGroup);
            app.RawdataButton.Text = 'Raw data';
            app.RawdataButton.Value = true;
            app.ODButton = uiradiobutton(app.PlotTypeButtonGroup);
            app.ODButton.Text = 'OD';
            app.ConcButton = uiradiobutton(app.PlotTypeButtonGroup);
            app.ConcButton.Text = 'Conc';
            app.ChannelDropDownLabel = uilabel(app.UIFigure);
            app.ChannelDropDownLabel.Text = 'Channel';
            app.ChannelDropDown = uidropdown(app.UIFigure);
            app.ChannelDropDown.Items = {};
            app.ChannelDropDown.Value = {};
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.SignalListBox = uilistbox(app.UIFigure);
            app.SignalListBox.Items = {};
            app.SignalListBox.Multiselect = 'on';
            app.SignalListBox.ClickedFcn = createCallbackFcn(app, @SignalListBoxClicked, true);
            app.SignalListBox.Value = {};
            app.ProcessinglevelButtonGroup = uibuttongroup(app.UIFigure);
            app.ProcessinglevelButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ProcessinglevelButtonGroupSelectionChanged, true);
            app.ProcessinglevelButtonGroup.Title = 'Processing level';
            app.GroupButton = uiradiobutton(app.ProcessinglevelButtonGroup);
            app.GroupButton.Text = 'Group';
            app.SubjButton = uiradiobutton(app.ProcessinglevelButtonGroup);
            app.SubjButton.Text = 'Subj';
            app.RunButton = uiradiobutton(app.ProcessinglevelButtonGroup);
            app.RunButton.Text = 'Run';
            app.RunButton.Value = true;
            app.SessButton = uiradiobutton(app.ProcessinglevelButtonGroup);
            app.SessButton.Text = 'Sess';
            app.DataTree = uitree(app.UIFigure);
            app.DataTree.SelectionChangedFcn = createCallbackFcn(app, @DataTreeSelectionChanged, true);
            app.HRFCheckBox = uicheckbox(app.UIFigure);
            app.HRFCheckBox.ValueChangedFcn = createCallbackFcn(app, @HRFCheckBoxValueChanged, true);
            app.HRFCheckBox.Text = 'HRF';
            app.ConditonDropDownLabel = uilabel(app.UIFigure);
            app.ConditonDropDownLabel.Text = 'Conditon';
            app.ConditonDropDown = uidropdown(app.UIFigure);
            app.ConditonDropDown.Items = {};
            app.ConditonDropDown.Enable = 'off';
            app.ConditonDropDown.Value = {};
            app.LoadSDButton = uibutton(app.UIFigure, 'push');
            app.LoadSDButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSDButtonPushed, true);
            app.LoadSDButton.Text = 'Load SD';
            app.sensitivityDropDownLabel = uilabel(app.UIFigure);
            app.sensitivityDropDownLabel.Text = 'sensitivity';
            app.sensitivityDropDown = uidropdown(app.UIFigure);
            app.sensitivityDropDown.Items = {'Head', 'Brain'};
            app.sensitivityDropDown.ValueChangedFcn = createCallbackFcn(app, @SensVis, true);
            app.sensitivityDropDown.Value = 'Head';
            app.ViewsingletimebaseButton = uibutton(app.UIFigure, 'push');
            app.ViewsingletimebaseButton.Text = 'View single time base';
            app.ViewcontinuetimebaseButton = uibutton(app.UIFigure, 'push');
            app.ViewcontinuetimebaseButton.Text = 'View continue time base';
            app.generatespatialbaseButton = uibutton(app.UIFigure, 'push');
            app.generatespatialbaseButton.Text = 'generate spatial base';
            app.rangeEditFieldLabel = uilabel(app.UIFigure);
            app.rangeEditFieldLabel.Text = 'range';
            app.rangeEditField = uieditfield(app.UIFigure, 'text');
            app.GPUCheckBox = uicheckbox(app.UIFigure);
            app.GPUCheckBox.Text = 'GPU';
            app.TextAreaLabel = uilabel(app.UIFigure);
            app.TextAreaLabel.Text = 'Text Area';
            app.StatusTextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = new_gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end